classdef Reciever_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConnectTab                     matlab.ui.container.Tab
        SerialPanel_Reciever           matlab.ui.container.Panel
        ConnectButton_Connect          matlab.ui.control.Button
        DisconnectResetButton_Connect  matlab.ui.control.Button
        ConnectedLamp_2Label           matlab.ui.control.Label
        ConnectedLamp_Connect          matlab.ui.control.Lamp
        SerialDevicesBox_Connect       matlab.ui.control.ListBox
        RefreshListButton_Connect      matlab.ui.control.Button
        OscillatorPanel                matlab.ui.container.Panel
        TheLocalOscillaotrcanbeprogrammedthroughttheFPGALabel  matlab.ui.control.Label
        SetOscillatorButton            matlab.ui.control.Button
        RAWTab                         matlab.ui.container.Tab
        DataPanel_RAW                  matlab.ui.container.Panel
        ConnectedLampLable_RAW         matlab.ui.control.Label
        ConnectedLamp_RAW              matlab.ui.control.Lamp
        GetDataButton_RAW              matlab.ui.control.Button
        NumberofsamplesLabel           matlab.ui.control.Label
        NumberofsamplesField_RAW       matlab.ui.control.NumericEditField
        EnableADC1_0_RAW               matlab.ui.control.CheckBox
        ChannelSelectLabel             matlab.ui.control.Label
        EnableADC1_1_RAW               matlab.ui.control.CheckBox
        EnableADC1_2_RAW               matlab.ui.control.CheckBox
        EnableADC1_3_RAW               matlab.ui.control.CheckBox
        EnableADC1_4_RAW               matlab.ui.control.CheckBox
        EnableADC1_5_RAW               matlab.ui.control.CheckBox
        EnableADC1_6_RAW               matlab.ui.control.CheckBox
        EnableADC1_7_RAW               matlab.ui.control.CheckBox
        EnableADC2_0_RAW               matlab.ui.control.CheckBox
        EnableADC2_1_RAW               matlab.ui.control.CheckBox
        EnableADC2_2_RAW               matlab.ui.control.CheckBox
        EnableADC2_3_RAW               matlab.ui.control.CheckBox
        EnableADC2_4_RAW               matlab.ui.control.CheckBox
        EnableADC2_5_RAW               matlab.ui.control.CheckBox
        EnableADC2_6_RAW               matlab.ui.control.CheckBox
        EnableADC2_7_RAW               matlab.ui.control.CheckBox
        ADC1ChSelLabel_RAW             matlab.ui.control.Label
        ADC2ChSelLabel_RAW             matlab.ui.control.Label
        GraphPanel_RAW                 matlab.ui.container.Panel
        ExportDataButton_RAW           matlab.ui.control.Button
        XShiftLeft_RAW                 matlab.ui.control.Button
        XShiftRight_RAW                matlab.ui.control.Button
        XShiftLabel_RAW                matlab.ui.control.Label
        XShiftHome_RAW                 matlab.ui.control.Button
        XZoomIn_RAW                    matlab.ui.control.Button
        XZoomOut_RAW                   matlab.ui.control.Button
        XZoomLabel_RAW                 matlab.ui.control.Label
        XZoomHome_RAW                  matlab.ui.control.Button
        RawGraphsGroup                 matlab.ui.container.TabGroup
        ADC1Tab_RAW                    matlab.ui.container.Tab
        ADC1_0Axes_RAW                 matlab.ui.control.UIAxes
        ADC1_2Axes_RAW                 matlab.ui.control.UIAxes
        ADC1_4Axes_RAW                 matlab.ui.control.UIAxes
        ADC1_6Axes_RAW                 matlab.ui.control.UIAxes
        ADC2Tab_RAW                    matlab.ui.container.Tab
        ADC2_0Axes_RAW                 matlab.ui.control.UIAxes
        ADC2_2Axes_RAW                 matlab.ui.control.UIAxes
        ADC2_4Axes_RAW                 matlab.ui.control.UIAxes
        ADC2_6Axes_RAW                 matlab.ui.control.UIAxes
        IQTab_RAW                      matlab.ui.container.Tab
        DSPTab                         matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function results = ConnectedSetEnables(app)
            results = 1;
            %Connect tab
            app.ConnectedLamp_Connect.Color = [0 1 0];
            app.ConnectButton_Connect.Enable = 0;
            app.SerialDevicesBox_Connect.Enable = 0;
            app.RefreshListButton_Connect.Enable = 0;
            %RAW tab
            app.ConnectedLamp_RAW.Color = [0 1 0];
            app.GetDataButton_RAW.Enable = 1;
            app.NumberofsamplesField_RAW.Enable = 1;
            app.NumberofsamplesLabel.Enable = 1;
            %DSP tab
        end
        
        function results = DisconnectedSetEnables(app)
            results = 1;
            %Connect tab
            app.ConnectedLamp_Connect.Color = [1 0 0];
            app.ConnectButton_Connect.Enable = 1;
            app.SerialDevicesBox_Connect.Enable = 1;
            app.RefreshListButton_Connect.Enable = 1;
            %RAW tab
            app.ConnectedLamp_RAW.Color = [1 0 0];
            app.GetDataButton_RAW.Enable = 0;
            app.NumberofsamplesField_RAW.Enable = 0;
            app.NumberofsamplesLabel.Enable = 1;
            %DSP tab
        end
        
        function results = GetData(app)
            try
                app.GetDataButton_RAW.Enable = 1;
                results = 1;
                global Serial
                global dataADC1
                global dataADC2
                global XZOOMVALUE
                global XOFFSETVALUE
                global NUMOFSAMPLES
                
                XOFFSETVALUE = 0;
                XZOOMVALUE = 0;
                %--- Set Number of Samples ---
                fprintf(Serial,'0');
                fwrite(Serial, NUMOFSAMPLES,'uint32');
                %--- Reciever Samples ---
                recieved = '';
                flushinput(Serial);
                
                %wait for sampling
                f = waitbar(0,'Please press sample','WindowStyle','modal');
                
                while(1) % Wait for ADC1 RAWDATA keyword
                    if(Serial.BytesAvailable)
                        recieved = (fgetl(Serial))
                    end
                    if contains(recieved,'ADC1 RAWDATA')
                        break
                    end
                    if(isvalid(Serial) == 0)
                        warndlg('ERROR: Serial not valid')
                        throw('Serial Error')
                    end
                end
                
                Serial.Timeout = 2; % so we don't wait as long on error
                
                %Read for ADC1
                waitbar(0,f,'Reading in data - ADC1')
                dataADC1 = zeros(8,NUMOFSAMPLES);
                for i = 1:(NUMOFSAMPLES) %Recieve and split
                    if(isvalid(Serial) == 0)
                        throw('Serial Error') %serial not valid
                    end
                    recieved = fgetl(Serial);
                    if contains(recieved,'RESET')
                        throw('Serial Error') %Not expecting to be reset while recieving
                    end
                    dataADC1(4, i) = hex2dec(recieved(2:3));
                    dataADC1(3, i) = hex2dec(recieved(4:5));
                    dataADC1(2, i) = hex2dec(recieved(6:7));
                    dataADC1(1, i) = hex2dec(recieved(8:9));
                    if (mod(i,NUMOFSAMPLES/10) == 0) %update wait bar
                        waitbar(i/NUMOFSAMPLES,f)
                        pause(0.01)
                    end
                end
                
                % Wait for ADC2 RAWDATA keyword
                while(1)
                    if(Serial.BytesAvailable) %Recieve only if available data
                        recieved = (fgetl(Serial));
                    end
                    if contains(recieved,'ADC2 RAWDATA')
                        break
                    end
                    if(isvalid(Serial) == 0)
                        warndlg('ERROR: Serial not valid')
                        throw('Serial Error')
                    end
                end
                %Read for ADC2
                waitbar(0,f,'Reading in data - ADC2')
                dataADC2 = zeros(8,NUMOFSAMPLES);
                for i = 1:(NUMOFSAMPLES) %Recieve and split
                    if(isvalid(Serial) == 0)
                        throw('Serial Error') %serial not valid
                    end
                    recieved = fgetl(Serial);
                    if contains(recieved,'RESET')
                        throw('Serial Error') %Not expecting to be reset while recieving
                    end
                    dataADC2(4, i) = hex2dec(recieved(2:3));
                    dataADC2(3, i) = hex2dec(recieved(4:5));
                    dataADC2(2, i) = hex2dec(recieved(6:7));
                    dataADC2(1, i) = hex2dec(recieved(8:9));
                    if (mod(i,NUMOFSAMPLES/10) == 0) %update wait bar
                        waitbar(i/NUMOFSAMPLES,f)
                        pause(0.01)
                    end
                end
                
                
                %plot the data
                waitbar(0,f,'Plotting data - ADC1')
                plot(app.ADC1_0Axes_RAW,dataADC1(1, 1:NUMOFSAMPLES ))
                ylim(app.ADC1_0Axes_RAW,[0 256])
                waitbar(0.25,f)
                plot(app.ADC1_2Axes_RAW,dataADC1(2, 1:NUMOFSAMPLES ))
                ylim(app.ADC1_2Axes_RAW,[0 256])
                waitbar(0.5,f)
                plot(app.ADC1_4Axes_RAW,dataADC1(3, 1:NUMOFSAMPLES ))
                ylim(app.ADC1_4Axes_RAW,[0 256])
                waitbar(0.75,f)
                plot(app.ADC1_6Axes_RAW,dataADC1(4, 1:NUMOFSAMPLES ))
                ylim(app.ADC1_6Axes_RAW,[0 256])
                
                waitbar(0,f,'Plotting data - ADC2')
                plot(app.ADC2_0Axes_RAW,dataADC2(1, 1:NUMOFSAMPLES ))
                ylim(app.ADC2_0Axes_RAW,[0 256])
                waitbar(0.25,f)
                plot(app.ADC2_2Axes_RAW,dataADC2(2, 1:NUMOFSAMPLES ))
                ylim(app.ADC2_2Axes_RAW,[0 256])
                waitbar(0.5,f)
                plot(app.ADC2_4Axes_RAW,dataADC2(3, 1:NUMOFSAMPLES ))
                ylim(app.ADC2_4Axes_RAW,[0 256])
                waitbar(0.75,f)
                plot(app.ADC2_6Axes_RAW,dataADC2(4, 1:NUMOFSAMPLES ))
                ylim(app.ADC2_6Axes_RAW,[0 256])
                
                XZOOMVALUE = 0;
                XOFFSETVALUE = 0;
                
                RedrawXAxis_Raw(app,1);
                
                %finish up
                close(f)
                pause(0.01)
                app.GetDataButton_RAW.Enable = 1;
                
            catch
                %Serial error
                try
                    close(f)
                catch
                end
                msgbox("ERROR: Error communicating with FPGA. Serial recieve error. Please reconnect");
                DisconnectResetButton_ConnectPushed(app);
            end
        end
        
        function results = RedrawXAxis_Raw(app,ADC)
            global NUMOFSAMPLES
            results = 1;
            
            %Check adc range
            if (ADC ~= 1 && ADC ~= 2)
                warndlg("ERROR: requested ADC view is not available")
                results = 0;
                return
            end
            
            %Zoom Check
            global XZOOMVALUE
            
            if XZOOMVALUE < 0
                warndlg("WARNING: Zoom limit reached")
                results = 0;
                return
            end   
            
            %Offset Check
            global XOFFSETVALUE;
            
            if XOFFSETVALUE < 0
                warndlg("WARNING: Offset limit reached")
                results = 0;
                return
            end
            
            XZoomAbsUpperLimit = NUMOFSAMPLES/(2^XZOOMVALUE);
            if (XZoomAbsUpperLimit * XOFFSETVALUE) >= NUMOFSAMPLES
                %Offset takes it past end of data
                XOFFSETVALUE = XOFFSETVALUE - 1;
                warndlg("WARNING: Offset limit reached")
                results = 0;
                return
            end
            
            %Calcs
            Offset = XZoomAbsUpperLimit * XOFFSETVALUE;
                
            %plots
            f = waitbar(0,'Drawing');
            if (ADC == 1)
                xlim(app.ADC1_0Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                xlim(app.ADC2_0Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                waitbar(0.25,f)
                xlim(app.ADC1_2Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                xlim(app.ADC2_2Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                waitbar(0.5,f)
                xlim(app.ADC1_4Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                xlim(app.ADC2_4Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                waitbar(0.75,f)
                xlim(app.ADC1_6Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                xlim(app.ADC2_6Axes_RAW,[Offset (XZoomAbsUpperLimit + Offset)])
                close(f)
            end
            if (ADC == 2)
                %Nothing here yet
            end


        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global NUMOFSAMPLES
            instrreset;
            app.SerialDevicesBox_Connect.Items = seriallist;
            app.NumberofsamplesField_RAW.Value = 200;
            NUMOFSAMPLES = app.NumberofsamplesField_RAW.Value;
        end

        % Button pushed function: ConnectButton_Connect
        function ConnectButton_ConnectPushed(app, event)
            global Serial
            try
                Serial = serial(app.SerialDevicesBox_Connect.Value,'baud',115200);
                fopen(Serial);
                if (Serial.status == "open")
                    ConnectedSetEnables(app);
                else
                    error('Cannot connect');
                end
            catch
                warndlg("ERROR: Cannot connect to port, is it in use?");
                app.SerialDevicesBox_Connect.Items = seriallist;
            end
        end

        % Button pushed function: DisconnectResetButton_Connect
        function DisconnectResetButton_ConnectPushed(app, event)
            global Serial
            try
                fclose(Serial);
                if (Serial.status == "closed")
                    DisconnectedSetEnables(app);
                end
                delete(Serial);
                clear Serial;
            catch
            end
            app.SerialDevicesBox_Connect.Items = seriallist;
        end

        % Button pushed function: RefreshListButton_Connect
        function RefreshListButton_ConnectPushed(app, event)
            app.SerialDevicesBox_Connect.Items = seriallist;
        end

        % Button pushed function: GetDataButton_RAW
        function GetDataButton_RAWPushed(app, event)
            GetData(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                global Serial
                fclose(Serial);
                delete(Serial);
                delete(app)
            catch
            end
        end

        % Value changed function: NumberofsamplesField_RAW
        function NumberofsamplesField_RAWValueChanged(app, event)
            global NUMOFSAMPLES
            NUMOFSAMPLES = app.NumberofsamplesField_RAW.Value;
        end

        % Button pushed function: ExportDataButton_RAW
        function ExportDataButton_RAWPushed(app, event)
            global dataADC1
            global dataADC2
            global NUMOFSAMPLES
            %try
            [file, path] = uiputfile('export.csv');
            data = zeros(8, NUMOFSAMPLES);
            data(1:4,1:NUMOFSAMPLES) = (dataADC1(1:4,1:NUMOFSAMPLES));
            data(5:8,1:NUMOFSAMPLES) = (dataADC2(1:4,1:NUMOFSAMPLES));
            csvwrite(strcat(path,file),data)
            %catch
            %   warndlg('ERROR: Unable to export')
            %end
        end

        % Button pushed function: XZoomIn_RAW
        function XZoomIn_RAWButtonPushed(app, event)
            global XZOOMVALUE
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            XZOOMVALUE = XZOOMVALUE +1;
            RedrawXAxis_Raw(app,1)
        end

        % Button pushed function: XZoomOut_RAW
        function XZoomOut_RAWButtonPushed(app, event)
            global XZOOMVALUE
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            XZOOMVALUE = XZOOMVALUE -1;
            RedrawXAxis_Raw(app,1)
        end

        % Button pushed function: XZoomHome_RAW
        function XZoomHome_RAWButtonPushed(app, event)
            global XZOOMVALUE
            XZOOMVALUE = 0;
            RedrawXAxis_Raw(app,1)
        end

        % Button pushed function: XShiftHome_RAW
        function XShiftHome_RAWButtonPushed(app, event)
            global XOFFSETVALUE
            XOFFSETVALUE = 0;
            RedrawXAxis_Raw(app,1)
        end

        % Button pushed function: XShiftLeft_RAW
        function XShiftLeft_RAWButtonPushed(app, event)
            global XOFFSETVALUE
            XOFFSETVALUE = XOFFSETVALUE -1;
            RedrawXAxis_Raw(app,1)
        end

        % Button pushed function: XShiftRight_RAW
        function XShiftRight_RAWButtonPushed(app, event)
            global XOFFSETVALUE
            XOFFSETVALUE = XOFFSETVALUE +1;
            RedrawXAxis_Raw(app,1)
        end

        % Button pushed function: SetOscillatorButton
        function SetOscillatorButtonPushed(app, event)
            global Serial
            try
            fprintf(Serial,'1');
            catch
                warndlg("Error: Unable to communicate with FPGA. Please reconnect.")
                DisconnectResetButton_ConnectPushed(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectTab = uitab(app.TabGroup);
            app.ConnectTab.Title = 'Connect';
            app.SerialPanel_Reciever = uipanel(app.ConnectTab);
            app.SerialPanel_Reciever.Title = 'Serial';
            app.ConnectButton_Connect = uibutton(app.SerialPanel_Reciever, 'push');
            app.ConnectButton_Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_ConnectPushed, true);
            app.ConnectButton_Connect.Text = 'Connect';
            app.DisconnectResetButton_Connect = uibutton(app.SerialPanel_Reciever, 'push');
            app.DisconnectResetButton_Connect.ButtonPushedFcn = createCallbackFcn(app, @DisconnectResetButton_ConnectPushed, true);
            app.DisconnectResetButton_Connect.Text = 'Disconnect/ Reset';
            app.ConnectedLamp_2Label = uilabel(app.SerialPanel_Reciever);
            app.ConnectedLamp_2Label.Text = 'Connected';
            app.ConnectedLamp_Connect = uilamp(app.SerialPanel_Reciever);
            app.SerialDevicesBox_Connect = uilistbox(app.SerialPanel_Reciever);
            app.RefreshListButton_Connect = uibutton(app.SerialPanel_Reciever, 'push');
            app.RefreshListButton_Connect.ButtonPushedFcn = createCallbackFcn(app, @RefreshListButton_ConnectPushed, true);
            app.RefreshListButton_Connect.Text = 'Refresh List';
            app.OscillatorPanel = uipanel(app.ConnectTab);
            app.OscillatorPanel.Title = 'Oscillator';
            app.TheLocalOscillaotrcanbeprogrammedthroughttheFPGALabel = uilabel(app.OscillatorPanel);
            app.TheLocalOscillaotrcanbeprogrammedthroughttheFPGALabel.Text = {'The Local Oscillator can be programmed throught the FPGA. Currently this is limited to 1.736GHz at'; 'maximum power. The FPGA will reset after the oscillaotor is set. There is also no feedback from the '; 'Oscillator so check the Locked LED! '};
            app.SetOscillatorButton = uibutton(app.OscillatorPanel, 'push');
            app.SetOscillatorButton.ButtonPushedFcn = createCallbackFcn(app, @SetOscillatorButtonPushed, true);
            app.SetOscillatorButton.Text = 'Set Oscillator';
            app.RAWTab = uitab(app.TabGroup);
            app.RAWTab.Title = 'Raw Data View';
            app.DataPanel_RAW = uipanel(app.RAWTab);
            app.DataPanel_RAW.Title = 'Data ';
            app.ConnectedLampLable_RAW = uilabel(app.DataPanel_RAW);
            app.ConnectedLampLable_RAW.Text = 'Connected:';
            app.ConnectedLamp_RAW = uilamp(app.DataPanel_RAW);
            app.GetDataButton_RAW = uibutton(app.DataPanel_RAW, 'push');
            app.GetDataButton_RAW.ButtonPushedFcn = createCallbackFcn(app, @GetDataButton_RAWPushed, true);
            app.GetDataButton_RAW.Enable = 'off';
            app.GetDataButton_RAW.Text = 'Get Data';
            app.NumberofsamplesLabel = uilabel(app.DataPanel_RAW);
            app.NumberofsamplesLabel.Enable = 'off';
            app.NumberofsamplesLabel.Text = 'Number of samples:';
            app.NumberofsamplesField_RAW = uieditfield(app.DataPanel_RAW, 'numeric');
            app.NumberofsamplesField_RAW.Limits = [0 8000];
            app.NumberofsamplesField_RAW.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplesField_RAWValueChanged, true);
            app.NumberofsamplesField_RAW.Enable = 'off';
            app.NumberofsamplesField_RAW.Value = 8000;
            app.EnableADC1_0_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_0_RAW.Enable = 'off';
            app.EnableADC1_0_RAW.Text = '0';
            app.EnableADC1_0_RAW.Value = true;
            app.ChannelSelectLabel = uilabel(app.DataPanel_RAW);
            app.ChannelSelectLabel.Enable = 'off';
            app.ChannelSelectLabel.Text = 'Channels:';
            app.EnableADC1_1_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_1_RAW.Enable = 'off';
            app.EnableADC1_1_RAW.Text = '1';
            app.EnableADC1_2_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_2_RAW.Enable = 'off';
            app.EnableADC1_2_RAW.Text = '2';
            app.EnableADC1_2_RAW.Value = true;
            app.EnableADC1_3_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_3_RAW.Enable = 'off';
            app.EnableADC1_3_RAW.Text = '3';
            app.EnableADC1_4_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_4_RAW.Enable = 'off';
            app.EnableADC1_4_RAW.Text = '4';
            app.EnableADC1_4_RAW.Value = true;
            app.EnableADC1_5_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_5_RAW.Enable = 'off';
            app.EnableADC1_5_RAW.Text = '5';
            app.EnableADC1_6_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_6_RAW.Enable = 'off';
            app.EnableADC1_6_RAW.Text = '6';
            app.EnableADC1_6_RAW.Value = true;
            app.EnableADC1_7_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC1_7_RAW.Enable = 'off';
            app.EnableADC1_7_RAW.Text = '7';
            app.EnableADC2_0_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_0_RAW.Enable = 'off';
            app.EnableADC2_0_RAW.Text = '0';
            app.EnableADC2_0_RAW.Value = true;
            app.EnableADC2_1_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_1_RAW.Enable = 'off';
            app.EnableADC2_1_RAW.Text = '1';
            app.EnableADC2_2_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_2_RAW.Enable = 'off';
            app.EnableADC2_2_RAW.Text = '2';
            app.EnableADC2_2_RAW.Value = true;
            app.EnableADC2_3_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_3_RAW.Enable = 'off';
            app.EnableADC2_3_RAW.Text = '3';
            app.EnableADC2_4_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_4_RAW.Enable = 'off';
            app.EnableADC2_4_RAW.Text = '4';
            app.EnableADC2_4_RAW.Value = true;
            app.EnableADC2_5_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_5_RAW.Enable = 'off';
            app.EnableADC2_5_RAW.Text = '5';
            app.EnableADC2_6_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_6_RAW.Enable = 'off';
            app.EnableADC2_6_RAW.Text = '6';
            app.EnableADC2_6_RAW.Value = true;
            app.EnableADC2_7_RAW = uicheckbox(app.DataPanel_RAW);
            app.EnableADC2_7_RAW.Enable = 'off';
            app.EnableADC2_7_RAW.Text = '7';
            app.ADC1ChSelLabel_RAW = uilabel(app.DataPanel_RAW);
            app.ADC1ChSelLabel_RAW.Enable = 'off';
            app.ADC1ChSelLabel_RAW.Text = 'ADC1';
            app.ADC2ChSelLabel_RAW = uilabel(app.DataPanel_RAW);
            app.ADC2ChSelLabel_RAW.Enable = 'off';
            app.ADC2ChSelLabel_RAW.Text = 'ADC2';
            app.GraphPanel_RAW = uipanel(app.RAWTab);
            app.GraphPanel_RAW.Title = 'Graph';
            app.ExportDataButton_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.ExportDataButton_RAW.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButton_RAWPushed, true);
            app.ExportDataButton_RAW.Text = 'Export Data';
            app.XShiftLeft_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.XShiftLeft_RAW.ButtonPushedFcn = createCallbackFcn(app, @XShiftLeft_RAWButtonPushed, true);
            app.XShiftLeft_RAW.Text = '<';
            app.XShiftRight_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.XShiftRight_RAW.ButtonPushedFcn = createCallbackFcn(app, @XShiftRight_RAWButtonPushed, true);
            app.XShiftRight_RAW.Text = '>';
            app.XShiftLabel_RAW = uilabel(app.GraphPanel_RAW);
            app.XShiftLabel_RAW.Text = 'X Offset:';
            app.XShiftHome_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.XShiftHome_RAW.ButtonPushedFcn = createCallbackFcn(app, @XShiftHome_RAWButtonPushed, true);
            app.XShiftHome_RAW.Text = {'='; ''};
            app.XZoomIn_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.XZoomIn_RAW.ButtonPushedFcn = createCallbackFcn(app, @XZoomIn_RAWButtonPushed, true);
            app.XZoomIn_RAW.Text = '+';
            app.XZoomOut_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.XZoomOut_RAW.ButtonPushedFcn = createCallbackFcn(app, @XZoomOut_RAWButtonPushed, true);
            app.XZoomOut_RAW.Text = '-';
            app.XZoomLabel_RAW = uilabel(app.GraphPanel_RAW);
            app.XZoomLabel_RAW.Text = 'X Zoom:';
            app.XZoomHome_RAW = uibutton(app.GraphPanel_RAW, 'push');
            app.XZoomHome_RAW.ButtonPushedFcn = createCallbackFcn(app, @XZoomHome_RAWButtonPushed, true);
            app.XZoomHome_RAW.Text = {'='; ''};
            app.RawGraphsGroup = uitabgroup(app.RAWTab);
            app.ADC1Tab_RAW = uitab(app.RawGraphsGroup);
            app.ADC1Tab_RAW.Title = 'ADC1';
            app.ADC1_0Axes_RAW = uiaxes(app.ADC1Tab_RAW);
            title(app.ADC1_0Axes_RAW, 'Channel 0')
            app.ADC1_0Axes_RAW.ZLim = [0 255];
            app.ADC1_2Axes_RAW = uiaxes(app.ADC1Tab_RAW);
            title(app.ADC1_2Axes_RAW, 'Channel 2')
            app.ADC1_4Axes_RAW = uiaxes(app.ADC1Tab_RAW);
            title(app.ADC1_4Axes_RAW, 'Channel 4')
            app.ADC1_6Axes_RAW = uiaxes(app.ADC1Tab_RAW);
            title(app.ADC1_6Axes_RAW, 'Channel 6')
            app.ADC2Tab_RAW = uitab(app.RawGraphsGroup);
            app.ADC2Tab_RAW.Title = 'ADC2';
            app.ADC2_0Axes_RAW = uiaxes(app.ADC2Tab_RAW);
            title(app.ADC2_0Axes_RAW, 'Channel 0')
            app.ADC2_0Axes_RAW.ZLim = [0 255];
            app.ADC2_2Axes_RAW = uiaxes(app.ADC2Tab_RAW);
            title(app.ADC2_2Axes_RAW, 'Channel 2')
            app.ADC2_4Axes_RAW = uiaxes(app.ADC2Tab_RAW);
            title(app.ADC2_4Axes_RAW, 'Channel 4')
            app.ADC2_6Axes_RAW = uiaxes(app.ADC2Tab_RAW);
            title(app.ADC2_6Axes_RAW, 'Channel 6')
            app.IQTab_RAW = uitab(app.RawGraphsGroup);
            app.IQTab_RAW.Title = 'I/Q';
            app.DSPTab = uitab(app.TabGroup);
            app.DSPTab.Title = 'DSP View';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Reciever_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end